classdef BataryaArayuzu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        SOC_Display5   matlab.ui.control.NumericEditField
        SOC_Display4   matlab.ui.control.NumericEditField
        SOC_Display3   matlab.ui.control.NumericEditField
        SOC_Display2   matlab.ui.control.NumericEditField
        SOC_Display1   matlab.ui.control.NumericEditField
        StatusLabel    matlab.ui.control.Label
        StartButton    matlab.ui.control.Button
        SOC_Gauge5     matlab.ui.control.Gauge
        Batarya5Label  matlab.ui.control.Label
        SOC_Gauge4     matlab.ui.control.Gauge
        Batarya4Label  matlab.ui.control.Label
        SOC_Gauge3     matlab.ui.control.Gauge
        Batarya3Label  matlab.ui.control.Label
        SOC_Gauge2     matlab.ui.control.Gauge
        Batarya2Label  matlab.ui.control.Label
        SOC_Gauge1     matlab.ui.control.Gauge
        Batarya1Label  matlab.ui.control.Label
        AkllebekeBataryaYnetimSistemiLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)


            app.StatusLabel.Text = 'Hesaplanıyor...';
            app.StatusLabel.FontColor = [0.85, 0.33, 0.10]; 
            app.StartButton.Enable = 'off';
            drawnow;

            try
                simOut = sim('Akillisebeke1', 'StopTime', '20', 'SaveFormat', 'Array');

                time_data = simOut.battery1_soc(:, 1); 
                
                d1 = simOut.battery1_soc(:, 2); 
                d2 = simOut.battery2_soc(:, 2);
                d3 = simOut.battery3_soc(:, 2);
                d4 = simOut.battery4_soc(:, 2);
                d5 = simOut.battery5_soc(:, 2);

                app.StatusLabel.Text = 'Canlı Oynatılıyor...';
                app.StatusLabel.FontColor = 'blue';
                
                sim_duration = 20;
                
                tic; 
                while true
                    real_time = toc;
                    if real_time >= sim_duration
                        break;
                    end
                    
                    [~, idx] = min(abs(time_data - real_time));
                
                    app.SOC_Gauge1.Value = d1(idx);
                    app.SOC_Gauge2.Value = d2(idx);
                    app.SOC_Gauge3.Value = d3(idx);
                    app.SOC_Gauge4.Value = d4(idx);
                    app.SOC_Gauge5.Value = d5(idx);
                    
                    app.SOC_Display1.Value = d1(idx);
                    app.SOC_Display2.Value = d2(idx);
                    app.SOC_Display3.Value = d3(idx);
                    app.SOC_Display4.Value = d4(idx);
                    app.SOC_Display5.Value = d5(idx);
            
                    drawnow limitrate; 
                end

                app.SOC_Gauge1.Value = d1(end);
                app.SOC_Gauge2.Value = d2(end);
                app.SOC_Gauge3.Value = d3(end);
                app.SOC_Gauge4.Value = d4(end);
                app.SOC_Gauge5.Value = d5(end);
                
                app.SOC_Display1.Value = d1(end);
                app.SOC_Display2.Value = d2(end);
                app.SOC_Display3.Value = d3(end);
                app.SOC_Display4.Value = d4(end);
                app.SOC_Display5.Value = d5(end);

                app.StatusLabel.Text = 'Tamamlandı.';
                app.StatusLabel.FontColor = [0, 0.5, 0];

            catch ME
                app.StatusLabel.Text = 'Hata!';
                app.StatusLabel.FontColor = 'red';
                uialert(app.UIFigure, ME.message, 'Hata Detayı');
            end
            app.StartButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AkllebekeBataryaYnetimSistemiLabel = uilabel(app.UIFigure);
            app.AkllebekeBataryaYnetimSistemiLabel.Text = 'Akıllı Şebeke Batarya Yönetim Sistemi';
            app.Batarya1Label = uilabel(app.UIFigure);
            app.Batarya1Label.Text = 'Batarya 1';
            app.SOC_Gauge1 = uigauge(app.UIFigure, 'circular');
            app.Batarya2Label = uilabel(app.UIFigure);
            app.Batarya2Label.Text = 'Batarya 2';
            app.SOC_Gauge2 = uigauge(app.UIFigure, 'circular');
            app.Batarya3Label = uilabel(app.UIFigure);
            app.Batarya3Label.Text = 'Batarya 3';
            app.SOC_Gauge3 = uigauge(app.UIFigure, 'circular');
            app.Batarya4Label = uilabel(app.UIFigure);
            app.Batarya4Label.Text = 'Batarya 4';
            app.SOC_Gauge4 = uigauge(app.UIFigure, 'circular');
            app.Batarya5Label = uilabel(app.UIFigure);
            app.Batarya5Label.Text = 'Batarya 5';
            app.SOC_Gauge5 = uigauge(app.UIFigure, 'circular');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Simülasyonu Başlat';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Simülasyon bekleniyor...';
            app.SOC_Display1 = uieditfield(app.UIFigure, 'numeric');
            app.SOC_Display1.Editable = 'off';
            app.SOC_Display2 = uieditfield(app.UIFigure, 'numeric');
            app.SOC_Display2.Editable = 'off';
            app.SOC_Display3 = uieditfield(app.UIFigure, 'numeric');
            app.SOC_Display3.Editable = 'off';
            app.SOC_Display4 = uieditfield(app.UIFigure, 'numeric');
            app.SOC_Display4.Editable = 'off';
            app.SOC_Display5 = uieditfield(app.UIFigure, 'numeric');
            app.SOC_Display5.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BataryaArayuzu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end